classdef EDS_Grain_Analyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        SelectGrainButton    matlab.ui.control.Button
        GrainEditField       matlab.ui.control.NumericEditField
        GrainEditFieldLabel  matlab.ui.control.Label
        SheetNameEditField   matlab.ui.control.EditField
        SheetNameLabel       matlab.ui.control.Label
        WriteDataButton      matlab.ui.control.Button
        LoadDataButton       matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        g = 1; % counter for grain selection
        
        start_path
        write_path
        excel_filename
        full_excel_filename
        data

        x
        y

        m = [0.75, -0.45];
        b = [0.83, 0.63];
        atomic_mass = [10.811, 12.011, 28.0855];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            cla(app.UIAxes)
        
            %% Prompt user to select 3 data files
            % Start in default folder

            if app.g == 1
                app.start_path = '*.csv';
            end
        
            [filename, path] = uigetfile(app.start_path,'Select EDS data files','MultiSelect','on');
            number_of_files = length(filename);
        
            folders_in_path = regexp(path,'[\\/]','split');
            num_folders = length(folders_in_path);
        
            if contains(path,'/')
                delim = '/';
            elseif contains(path,'\')
                delim = '\';
            end
        
            folders_in_path(num_folders-1:num_folders) = '';

            if app.g == 1
                app.write_path = append(strjoin(folders_in_path,delim),delim);
                app.start_path = append(app.write_path,'*.csv');
            else
                app.start_path = append(strjoin(folders_in_path,delim),delim,'*.csv');
            end
        
            % Define filename to write data to
            app.excel_filename = 'EDS Grain Analysis.xlsx';
            app.full_excel_filename = fullfile(app.write_path, app.excel_filename);
        
            %% Read Data
            map_size = size(readmatrix(strcat(path,filename{1})));
            app.data = zeros(map_size(1),map_size(2),number_of_files);
        
            for i = 1:number_of_files
                % create path to file
                mapFile = strcat(path,filename{i});
        
                % read the data file
                app.data(:,:,i) = readmatrix(mapFile); % loads data from the file into a matrix
            end
    
            imshow(app.data, 'Parent', app.UIAxes)
            % Remove tick labels
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            hold on
        end

        % Button pushed function: WriteDataButton
        function WriteDataButtonPushed(app, event)
            sheetname = app.SheetNameEditField.Value;

            if isempty(sheetname)
                sheetname = 'Default';
                % check if sheetname exceeds Excel's 31 character limit and truncate it
            elseif size(sheetname,2)>31
                sheetname = sheetname(1:31);
            end
            
            %% Compute average intensity for each grain
            [counts, intensity_ratio, scaling_factor, rel_intensity, wt_fraction, mol, at_percent] = deal(ones(app.g - 1,3));
        
            for i = 1:app.g -1 
                counts(i,1) = mean(app.data(app.y(i,1):app.y(i,2), app.x(i,1):app.x(i,2), 1), 'all');
                counts(i,2) = mean(app.data(app.y(i,1):app.y(i,2), app.x(i,1):app.x(i,2), 2), 'all');
                counts(i,3) = mean(app.data(app.y(i,1):app.y(i,2), app.x(i,1):app.x(i,2), 3), 'all');
            end
    
            %% Apply the Scaling Factors
            for i = 2:3
                % compute elemental intensity ratios to be used in scaling factor calcs
                intensity_ratio(:,i) = counts(:,i) ./ counts(:,1);
                % calculate scaling factors for each point along line averaged intensity
                scaling_factor(:,i) = app.b(i-1) * exp(app.m(i-1) .* intensity_ratio(:,i));
                % apply scaling factors to get corrected relative intensity of each element
                rel_intensity(:,i) = scaling_factor(:,i) .* intensity_ratio(:,i);
            end
        
            total_intensity = rel_intensity(:,1) + rel_intensity(:,2) + rel_intensity(:,3);
        
            for i = 1:3
                % convert corrected intensities to weight fractions
                wt_fraction(:,i) = rel_intensity(:,i) ./ total_intensity;
                % convert weight fractions to relative mol fraction
                mol(:,i) = wt_fraction(:,i) / app.atomic_mass(i);
            end
        
            % convert relative mol fraction to atomic percents
            total_mols = mol(:,1) + mol(:,2) + mol(:,3);
        
            for i = 1:3
                at_percent(:,i) = 100 * mol(:,i) ./ total_mols;
            end
        
            b_to_c_ratio = at_percent(:,1) ./ at_percent(:,2);
        
            %% Prepare data for export
            % combine the 2theta, counts, and normalized counts columns
            two_column_data = array2table(num2cell([at_percent(:,3), b_to_c_ratio]));
        
            % create data headers to label the columns]
            column_labels = {'Si (at. %)', 'B/C Ratio'};
            separator = {blanks(1), blanks(1)};
        
            % combine data and headers into one big table to write to Excel
            composite_data = [column_labels; two_column_data; separator];
        
            %% Write data to output Excel spreadsheet
            writetable(composite_data,app.full_excel_filename,'Sheet',sheetname,'WriteVariableNames',false,'WriteMode','append');
        
            % final printout
            fprintf('\nComplete. Sheets written to [%s] in [%s] \n', app.excel_filename, app.write_path)
        end

        % Button pushed function: SelectGrainButton
        function SelectGrainButtonPushed(app, event)
%             [x_coord, y_coord] = ginput(2);
%             
%             app.x(app.g,:) = x_coord;
%             app.y(app.g,:) = y_coord;
%             
%             % Determine corner and dimensions
%             x0 = min(app.x(app.g,1), app.x(app.g,2));
%             y0 = min(app.y(app.g,1), app.y(app.g,2));
%             w = abs(app.x(app.g,2) - app.x(app.g,1));
%             h = abs(app.y(app.g,2) - app.y(app.g,1));
%     
%             % Plot rectangle on top of image
%             rectangle('Position', [x0 y0 w h], 'EdgeColor', 'r', 'LineWidth', 2, 'Parent', app.UIAxes)
%     
%             % Store values
%             % Start is bottom left and end is top right
%             app.x(app.g,1) = ceil(x0); app.x(app.g,2) = floor(x0 + w);
%             app.y(app.g,1) = ceil(y0); app.y(app.g,2) = floor(y0 + h);
%             
%             app.g = app.g + 1;
%             app.GrainEditField.Value = app.g;

            [x_coord, y_coord] = ginput(2);
            
            app.x(app.g,:) = x_coord;
            app.y(app.g,:) = y_coord;
            
            % Determine corner and dimensions
            x0 = min(app.x(app.g,1), app.x(app.g,2));
            y0 = min(app.y(app.g,1), app.y(app.g,2));
            w = abs(app.x(app.g,2) - app.x(app.g,1));
            h = abs(app.y(app.g,2) - app.y(app.g,1));
    
            % Plot rectangle on top of image
            rectangle('Position', [x0 y0 w h], 'EdgeColor', 'r', 'LineWidth', 2, 'Parent', app.UIAxes)
    
            % Store values
            % Start is bottom left and end is top right
            app.x(app.g,1) = ceil(x0); app.x(app.g,2) = floor(x0 + w);
            app.y(app.g,1) = ceil(y0); app.y(app.g,2) = floor(y0 + h);
            
            app.g = app.g + 1;
            app.GrainEditField.Value = app.g - 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.UIAxes.Visible = 'off';
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.WriteDataButton = uibutton(app.UIFigure, 'push');
            app.WriteDataButton.ButtonPushedFcn = createCallbackFcn(app, @WriteDataButtonPushed, true);
            app.WriteDataButton.Text = 'Write Data';
            app.SheetNameLabel = uilabel(app.UIFigure);
            app.SheetNameLabel.Text = 'Sheet Name:';
            app.SheetNameEditField = uieditfield(app.UIFigure, 'text');
            app.GrainEditFieldLabel = uilabel(app.UIFigure);
            app.GrainEditFieldLabel.Text = 'Grain:';
            app.GrainEditField = uieditfield(app.UIFigure, 'numeric');
            app.SelectGrainButton = uibutton(app.UIFigure, 'push');
            app.SelectGrainButton.ButtonPushedFcn = createCallbackFcn(app, @SelectGrainButtonPushed, true);
            app.SelectGrainButton.Text = 'Select Grain';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EDS_Grain_Analyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end